classdef ProjectS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EditField_2       matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        DropDown_10       matlab.ui.control.DropDown
        DropDown_10Label  matlab.ui.control.Label
        DropDown_9        matlab.ui.control.DropDown
        DropDown_9Label   matlab.ui.control.Label
        DropDown_8        matlab.ui.control.DropDown
        DropDown_8Label   matlab.ui.control.Label
        DropDown_7        matlab.ui.control.DropDown
        DropDown_7Label   matlab.ui.control.Label
        DropDown_6        matlab.ui.control.DropDown
        DropDown_6Label   matlab.ui.control.Label
        DropDown_5        matlab.ui.control.DropDown
        DropDown_5Label   matlab.ui.control.Label
        DropDown_4        matlab.ui.control.DropDown
        DropDown_4Label   matlab.ui.control.Label
        DropDown_3        matlab.ui.control.DropDown
        DropDown_3Label   matlab.ui.control.Label
        DropDown_2        matlab.ui.control.DropDown
        DropDown_2Label   matlab.ui.control.Label
        DropDown          matlab.ui.control.DropDown
        Label             matlab.ui.control.Label
    end

    
    properties (Access = private)
gpd table;
t;st;z;z2; z3; z4; z5; z6; z7; z8; z9; z10;
c;pr;m;countries;cfound;w1;A;B;Asor;Bsor;q;d;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
app.gpd=readtable("Table.xls");
app.DropDown.Items=[app.gpd.CountryName];
app.DropDown_2.Items=[app.gpd.CountryName];
app.DropDown_3.Items=[app.gpd.CountryName];
app.DropDown_4.Items=[app.gpd.CountryName];
app.DropDown_5.Items=[app.gpd.CountryName];
app.DropDown_6.Items=[app.gpd.CountryName];
app.DropDown_7.Items=[app.gpd.CountryName];
app.DropDown_8.Items=[app.gpd.CountryName];
app.DropDown_9.Items=[app.gpd.CountryName];
app.DropDown_10.Items=[app.gpd.CountryName];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

       country=app.DropDown.Value;
       app.st=table2array(app.gpd(string(app.gpd.CountryName(:))==country,2:60));
       for i=1:59
           if (app.st(1,i)==app.pr)
              app.z=i;
              break
           end
       end
       country=app.DropDown_2.Value;
       app.st=table2array(app.gpd(string(app.gpd.CountryName(:))==country,2:60));
       for i=1:59
           if (app.st(1,i)==app.pr)
              app.z2=i;
              break
           end
       end
       country=app.DropDown_3.Value;
       app.st=table2array(app.gpd(string(app.gpd.CountryName(:))==country,2:60));
       for i=1:59
           if (app.st(1,i)==app.pr)
              app.z3=i;
              break
           end
       end
       country=app.DropDown_4.Value;
       app.st=table2array(app.gpd(string(app.gpd.CountryName(:))==country,2:60));
       for i=1:59
           if (app.st(1,i)==app.pr)
              app.z4=i;
              break
           end
       end
       country=app.DropDown_5.Value;
       app.st=table2array(app.gpd(string(app.gpd.CountryName(:))==country,2:60));
       for i=1:59
           if (app.st(1,i)==app.pr)
              app.z5=i;
              break
           end
       end
       country=app.DropDown_6.Value;
       app.st=table2array(app.gpd(string(app.gpd.CountryName(:))==country,2:60));
       for i=1:59
           if (app.st(1,i)==app.pr)
              app.z6=i;
              break
           end
       end
       country=app.DropDown_7.Value;
       app.st=table2array(app.gpd(string(app.gpd.CountryName(:))==country,2:60));
       for i=1:59
           if (app.st(1,i)==app.pr)
              app.z7=i;
              break
           end
       end
       country=app.DropDown_8.Value;
       app.st=table2array(app.gpd(string(app.gpd.CountryName(:))==country,2:60));
       for i=1:59
           if (app.st(1,i)==app.pr)
              app.z8=i;
              break
           end
       end
       country=app.DropDown_9.Value;
       app.st=table2array(app.gpd(string(app.gpd.CountryName(:))==country,2:60));
       for i=1:59
           if (app.st(1,i)==app.pr)
              app.z9=i;
              break
           end
       end
       country=app.DropDown_10.Value;
       app.st=table2array(app.gpd(string(app.gpd.CountryName(:))==country,2:60));
       for i=1:59
           if (app.st(1,i)==app.pr)
              app.z10=i;
              break
           end
       end
       app.A=[app.z app.z2 app.z3 app.z4 app.z5 app.z6 app.z7 app.z8 app.z9 app.z10];
       app.B=[app.DropDown.Value app.DropDown_2.Value app.DropDown_3.Value app.DropDown_4.Value app.DropDown_5.Value app.DropDown_6.Value app.DropDown_7.Value app.DropDown_8.Value app.DropDown_9.Value app.DropDown_10.Value];
[app.Asor,R]=sort(app.A);
app.Bsor=app.B(R);
fileID=fopen('Result.txt','w');
fprintf(fileID,'%s\n',app.Bsor);
fclose(fileID);
       
      
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            app.pr = app.EditField.Value;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.m(1) = string(app.DropDown.Value);
            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            app.m(2) = string(app.DropDown_2.Value);
            
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            app.m(3) = string(app.DropDown_3.Value);
            
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            app.m(4) = string(app.DropDown_4.Value);
            
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            app.m(5) = string(app.DropDown_2.Value);
            
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            app.m(6) = string(app.DropDown_6.Value);
            
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
            app.m(7) = string(app.DropDown_7.Value);
            
        end

        % Value changed function: DropDown_8
        function DropDown_8ValueChanged(app, event)
            app.m(8) = string(app.DropDown_8.Value);
            
        end

        % Value changed function: DropDown_9
        function DropDown_9ValueChanged(app, event)
            app.m(9) = string(app.DropDown_9.Value);
            
        end

        % Value changed function: DropDown_10
        function DropDown_10ValueChanged(app, event)
            app.m(10) = string(app.DropDown_10.Value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Страна 1';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = 'Страна 2';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_3Label = uilabel(app.UIFigure);
            app.DropDown_3Label.Text = 'Страна 3';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_4Label = uilabel(app.UIFigure);
            app.DropDown_4Label.Text = 'Страна 4';
            app.DropDown_4 = uidropdown(app.UIFigure);
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_5Label = uilabel(app.UIFigure);
            app.DropDown_5Label.Text = 'Страна 5';
            app.DropDown_5 = uidropdown(app.UIFigure);
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_6Label = uilabel(app.UIFigure);
            app.DropDown_6Label.Text = 'Страна 6';
            app.DropDown_6 = uidropdown(app.UIFigure);
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_7Label = uilabel(app.UIFigure);
            app.DropDown_7Label.Text = 'Страна 7';
            app.DropDown_7 = uidropdown(app.UIFigure);
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
            app.DropDown_8Label = uilabel(app.UIFigure);
            app.DropDown_8Label.Text = 'Страна 8';
            app.DropDown_8 = uidropdown(app.UIFigure);
            app.DropDown_8.ValueChangedFcn = createCallbackFcn(app, @DropDown_8ValueChanged, true);
            app.DropDown_9Label = uilabel(app.UIFigure);
            app.DropDown_9Label.Text = 'Страна 9';
            app.DropDown_9 = uidropdown(app.UIFigure);
            app.DropDown_9.ValueChangedFcn = createCallbackFcn(app, @DropDown_9ValueChanged, true);
            app.DropDown_10Label = uilabel(app.UIFigure);
            app.DropDown_10Label.Text = 'Страна 10';
            app.DropDown_10 = uidropdown(app.UIFigure);
            app.DropDown_10.ValueChangedFcn = createCallbackFcn(app, @DropDown_10ValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Загрузить таблицу';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Вывести страны';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Введите процент с точостью до 0,1';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end