classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Button        matlab.ui.control.Button
        Label         matlab.ui.control.Label
        EditField     matlab.ui.control.NumericEditField
        EditField3    matlab.ui.control.NumericEditField
        Label_2       matlab.ui.control.Label
        Label_3       matlab.ui.control.Label
        EditField7    matlab.ui.control.NumericEditField
        EditField_2   matlab.ui.control.EditField
        EditField2    matlab.ui.control.EditField
        EditField3_2  matlab.ui.control.EditField
        EditField_4   matlab.ui.control.EditField
        plEditField   matlab.ui.control.EditField
        EditField_5   matlab.ui.control.EditField
        Label_8       matlab.ui.control.Label
        Label_9       matlab.ui.control.Label
        Label_10      matlab.ui.control.Label
        Label_11      matlab.ui.control.Label
    end

    
    properties (Access = private)
        a;
        b;
        c;
        
        sum_min=250;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      
        end

        % Callback function
        function EditField4ValueChanged(app, event)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           sum=app.a+app.b+app.c;
           app.Label_10.Text=sprintf('%d',sum);
           if(app.sum_min<=sum)
               app.Label_8.Text = 'Поздравляем!';
           else
               app.Label_8.Text = 'Не поздравляем';
           end
           
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.a = app.EditField.Value;
           
        end

        % Value changed function: EditField7
        function EditField7ValueChanged(app, event)
            app.b = app.EditField7.Value;
            
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            app.c = app.EditField3.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычисление';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Фамилия, имя, отчество';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Предмет';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Баллы ЕГЭ';
            app.EditField7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField7.ValueChangedFcn = createCallbackFcn(app, @EditField7ValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField3_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.plEditField = uieditfield(app.UIFigure, 'text');
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'Сумма баллов:';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'Проходной балл: 250';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end